classdef audiosamplergui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Slot9Button                     matlab.ui.control.Button
        Slot8Button                     matlab.ui.control.Button
        Slot7Button                     matlab.ui.control.Button
        Slot4Button                     matlab.ui.control.Button
        Slot6Button                     matlab.ui.control.Button
        Slot5Button                     matlab.ui.control.Button
        Slot3Button                     matlab.ui.control.Button
        Slot2Button                     matlab.ui.control.Button
        Slot1Button                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ChooseSlotDropDown              matlab.ui.control.DropDown
        ChooseSlotDropDownLabel         matlab.ui.control.Label
        ChooseWaveFunctionDropDown      matlab.ui.control.DropDown
        ChooseWaveFunctionDropDownLabel  matlab.ui.control.Label
        CButton                         matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        AButton                         matlab.ui.control.Button
        GButton                         matlab.ui.control.Button
        FButton                         matlab.ui.control.Button
        EButton                         matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        HzSlider_3                      matlab.ui.control.RangeSlider
        HzSlider_3Label                 matlab.ui.control.Label
        HzSlider_2                      matlab.ui.control.RangeSlider
        HzSlider_2Label                 matlab.ui.control.Label
        HzSlider                        matlab.ui.control.RangeSlider
        HzSliderLabel                   matlab.ui.control.Label
        VolumeKnob                      matlab.ui.control.Knob
        VolumeKnobLabel                 matlab.ui.control.Label
        PlaybackDirectionDropDown       matlab.ui.control.DropDown
        PlaybackDirectionDropDownLabel  matlab.ui.control.Label
        FeedbackKnob                    matlab.ui.control.Knob
        FeedbackKnobLabel               matlab.ui.control.Label
        DelayTimeKnob                   matlab.ui.control.Knob
        DelayTimeKnobLabel              matlab.ui.control.Label
        EchoSlider                      matlab.ui.control.RangeSlider
        EchoSliderLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %A structure for the sampler save slots
        saveSlots = struct('sample', cell(1,9), 'fs', cell(1,9));  %A structure for the save slots stores sample and frequency
        selectedWaveform = 'Sine' % automatically set the waveform to Sine if nothing chosen
        audioPlaying; % audioPlayer object
        waveSave; % Property for saving the waveform selected from dropdown
        selectedSlot; %Property for saving the slot selected by the user on the dropdown
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: ChooseWaveFunctionDropDown
        function ChooseWaveFunctionDropDownClicked(app, event)
           app.selectedWaveform = app.ChooseWaveFunctionDropDown.Value; %store the user selected waveform for later
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
         if(~isempty(app.audioPlaying))
                stop(app.audioPlaying)
            end
            afreq = 261.63;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying, wave, time] = generateSawtooth(afreq);
                play(app.audioPlaying)
                synthWave = plot(app.UIAxes, time, wave)
                xlim(app.UIAxes,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
              [app.audioPlaying, wave, time] = generateSine(afreq);
              play(app.audioPlaying)
              synthWave = plot(app.UIAxes, time, wave)
              xlim(app.UIAxes,[0,0.01])
              app.waveSave = wave;
        end

        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 293.7;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
           [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end

        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 329.6;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
           [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
             if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 349.2;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
           [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
             if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 392.0;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
                     [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end 
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
              if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 440;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
                     [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
             if(~isempty(app.audioPlaying))
            stop(app.audioPlaying)
            end
            afreq = 493.88;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
          app.audioPlaying = generateSawtooth(afreq);
          play(app.audioPlaying)
           [app.audioPlaying, wave, time] = generateSawtooth(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
           xlim(app.UIAxes,[0,0.01])
           app.waveSave = wave;
            end
        if(strcmp(app.selectedWaveform, 'Sine'))
           app.audioPlaying = generateSine(afreq);
           play(app.audioPlaying)
             [app.audioPlaying, wave, time] = generateSine(afreq);
           play(app.audioPlaying)
           synthWave = plot(app.UIAxes, time, wave)
            xlim(app.UIAxes,[0,0.01])
            app.waveSave = wave;
        end
        end

        % Value changed function: ChooseSlotDropDown
        function ChooseSlotDropDownValueChanged(app, event)
            value = app.ChooseSlotDropDown.Value;
            app.selectedSlot = value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Throw an error if the user tries to save without generating
            %the tone
           if(isempty(app.waveSave))
           uialert(app.UIFigure, 'Please generate a tone before saving!', 'You have nothing to save  ᕙ(⇀‸↼‶)ᕗ')
           return
           end
           %Throw an error if the user tries to save without selecting the
           %slot
           if isempty(app.selectedSlot)
           uialert(app.UIFigure, 'Please select a slot!', 'You have no slot chosen ( ͡ಠ ʖ̯ ͡ಠ )', 'Icon', 'warning');
           return
           end
           slotNum = str2double(app.selectedSlot(end)) %get the value of the slot
           app.saveSlots(slotNum).sample = app.waveSave;
           app.saveSlots(slotNum).fs = 44100;
        end

        % Button pushed function: Slot1Button
        function Slot1ButtonPushed(app, event)
           [file,location] = uigetfile('*.wav,*.flac,*.mp3');
           %Get the full path of the audiofile for audioread object
    if(file == 0)
        uialert(app.UIFigure, 'Please select a file', 'No file selected')
        return
    end

           fullPath = fullfile(location, file);
           [signal, fs] = audioread(fullPath);
           app.saveSlots(1).sample = signal;
           app.saveSlots(1).fs = fs;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Synthesizer')
            xlabel(app.UIAxes, {'Time'; ''})
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.EchoSliderLabel = uilabel(app.UIFigure);
            app.EchoSliderLabel.Text = 'Echo';
            app.EchoSlider = uislider(app.UIFigure, 'range');
            app.DelayTimeKnobLabel = uilabel(app.UIFigure);
            app.DelayTimeKnobLabel.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.UIFigure, 'continuous');
            app.DelayTimeKnob.Limits = [0 2];
            app.FeedbackKnobLabel = uilabel(app.UIFigure);
            app.FeedbackKnobLabel.Text = 'Feedback';
            app.FeedbackKnob = uiknob(app.UIFigure, 'continuous');
            app.FeedbackKnob.Limits = [0 0.8];
            app.PlaybackDirectionDropDownLabel = uilabel(app.UIFigure);
            app.PlaybackDirectionDropDownLabel.Text = 'Playback Direction';
            app.PlaybackDirectionDropDown = uidropdown(app.UIFigure);
            app.PlaybackDirectionDropDown.Items = {'Forward, Backward'};
            app.PlaybackDirectionDropDown.Value = 'Forward, Backward';
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob.Limits = [0 80];
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '32 Hz';
            app.HzSlider = uislider(app.UIFigure, 'range');
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '32 Hz';
            app.HzSlider_2 = uislider(app.UIFigure, 'range');
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '32 Hz';
            app.HzSlider_3 = uislider(app.UIFigure, 'range');
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = {'C'; ''};
            app.ChooseWaveFunctionDropDownLabel = uilabel(app.UIFigure);
            app.ChooseWaveFunctionDropDownLabel.Text = 'Choose Wave Function';
            app.ChooseWaveFunctionDropDown = uidropdown(app.UIFigure);
            app.ChooseWaveFunctionDropDown.Items = {'Sine', 'Sawtooth'};
            app.ChooseWaveFunctionDropDown.ClickedFcn = createCallbackFcn(app, @ChooseWaveFunctionDropDownClicked, true);
            app.ChooseWaveFunctionDropDown.Value = 'Sine';
            app.ChooseSlotDropDownLabel = uilabel(app.UIFigure);
            app.ChooseSlotDropDownLabel.Text = 'Choose Slot';
            app.ChooseSlotDropDown = uidropdown(app.UIFigure);
            app.ChooseSlotDropDown.Items = {'Slot 1', 'Slot 2', 'Slot 3', 'Slot 4', 'Slot 5', 'Slot 6', 'Slot 7', 'Slot 8', 'Slot 9'};
            app.ChooseSlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSlotDropDownValueChanged, true);
            app.ChooseSlotDropDown.Value = 'Slot 1';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save'; ''};
            app.Slot1Button = uibutton(app.UIFigure, 'push');
            app.Slot1Button.ButtonPushedFcn = createCallbackFcn(app, @Slot1ButtonPushed, true);
            app.Slot1Button.Text = 'Slot 1';
            app.Slot2Button = uibutton(app.UIFigure, 'push');
            app.Slot2Button.Text = 'Slot 2';
            app.Slot3Button = uibutton(app.UIFigure, 'push');
            app.Slot3Button.Text = 'Slot 3';
            app.Slot5Button = uibutton(app.UIFigure, 'push');
            app.Slot5Button.Text = 'Slot 5';
            app.Slot6Button = uibutton(app.UIFigure, 'push');
            app.Slot6Button.Text = 'Slot 6';
            app.Slot4Button = uibutton(app.UIFigure, 'push');
            app.Slot4Button.Text = 'Slot 4';
            app.Slot7Button = uibutton(app.UIFigure, 'push');
            app.Slot7Button.Text = 'Slot 7';
            app.Slot8Button = uibutton(app.UIFigure, 'push');
            app.Slot8Button.Text = 'Slot 8';
            app.Slot9Button = uibutton(app.UIFigure, 'push');
            app.Slot9Button.Text = 'Slot 9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiosamplergui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end