classdef audiosamplergui_newfseventhedition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EndingTimeSlider                matlab.ui.control.Slider
        EndingTimeSliderLabel           matlab.ui.control.Label
        StartingTimeSlider              matlab.ui.control.Slider
        StartingTimeSliderLabel         matlab.ui.control.Label
        ChooseWaveFunctionDropDown      matlab.ui.control.DropDown
        ChooseWaveFunctionDropDownLabel  matlab.ui.control.Label
        SelectSamplePanel               matlab.ui.container.Panel
        StopButton_2                    matlab.ui.control.Button
        PlayButton_2                    matlab.ui.control.Button
        ChooseSampleDropDown_2          matlab.ui.control.DropDown
        ChooseSampleDropDown_2Label_2   matlab.ui.control.Label
        Slot9Button                     matlab.ui.control.Button
        Slot8Button                     matlab.ui.control.Button
        Slot7Button                     matlab.ui.control.Button
        Slot6Button                     matlab.ui.control.Button
        Slot5Button                     matlab.ui.control.Button
        Slot4Button                     matlab.ui.control.Button
        Slot3Button                     matlab.ui.control.Button
        Slot2Button                     matlab.ui.control.Button
        Slot1Button                     matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ChoppingPanel                   matlab.ui.container.Panel
        ChooseSampleDropDown            matlab.ui.control.DropDown
        ChooseSampleLabel               matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        FeaturesPanel                   matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        DelayTimeKnobLabel_2            matlab.ui.control.Label
        PlaybackDirectionDropDownLabel_3  matlab.ui.control.Label
        VolumeKnob                      matlab.ui.control.Knob
        DelayTimeKnob                   matlab.ui.control.Knob
        DelayTimeKnob_3Label            matlab.ui.control.Label
        FeedbackKnobLabel_2             matlab.ui.control.Label
        EchoSliderLabel_2               matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        FeedbackKnob                    matlab.ui.control.Knob
        PlaybackSpeedSlider             matlab.ui.control.Slider
        PlaybackSpeedLabel              matlab.ui.control.Label
        ChooseSampleDropDown_1          matlab.ui.control.DropDown
        ChooseSampleDropDown_2Label     matlab.ui.control.Label
        SaveButton_3                    matlab.ui.control.Button
        PlaybackDirectionDropDown       matlab.ui.control.DropDown
        PlaybackDirectionDropDownLabel  matlab.ui.control.Label
        HzSlider_5                      matlab.ui.control.Slider
        HzLabel_4                       matlab.ui.control.Label
        HzSlider_4                      matlab.ui.control.Slider
        HzLabel_3                       matlab.ui.control.Label
        HzSlider_3                      matlab.ui.control.Slider
        HzLabel_2                       matlab.ui.control.Label
        HzSlider_2                      matlab.ui.control.Slider
        HzLabel                         matlab.ui.control.Label
        HzSlider_1                      matlab.ui.control.Slider
        HzSlider_5Label                 matlab.ui.control.Label
        SynthesizerPanel                matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        ChooseSampleDropDown_3          matlab.ui.control.DropDown
        ChooseSampleDropDown_4Label     matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        FButton                         matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        GButton                         matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        DButton                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        CButton                         matlab.ui.control.Button
        AButton                         matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        EButton                         matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %A structure for the sampler save slots
        audioTable % Stores audio samples and sample rates
        audioData = cell(9,1); % Stores audio samples
        sampleRate = cell(9,1); %A structure for the save slots stores sample and frequency
        selectedWaveform = 'Sine' % Automatically set the waveform to Sine if nothing chosen
        audioPlaying1; % AudioPlayer object 1
        audioPlaying2; % AudioPlayer object 2
        waveSave; % Property for saving the waveform selected from dropdown
        selectedSlot = 'Select'; %Property for saving the slot selected by the user on the dropdown
        eqGains = [0, 0, 0, 0, 0]; %Property for gains in decibel
        fs = 44100;

        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initializeApp(app)
            audioData = cell(9,1);
            sampleRate = cell(9,1);
            app.audioTable = table(audioData, sampleRate);
            app.StartingTimeSlider.Limits = [0, 100];
            app.EndingTimeSlider.Limits   = [0, 100];
            app.EndingTimeSlider.Value    = 100;
        end

        function playProcessedAudio(app)
            slotName = app.ChooseSampleDropDown_1.Value;
            if strcmp(slotName,'Select'), return; end
            slotNum = str2double(slotName(end));
            if isempty(app.audioData{slotNum}), return; end
        
            signal = processAudioPipeline(app, slotNum);
            fs = app.sampleRate{slotNum};
        
            if ~isempty(app.audioPlaying1)
                stop(app.audioPlaying1)
            end
        
            % Normalize first
            m = max(abs(signal));
            if m > 0
                signal = signal / m;
            end
        
            % Then apply volume AFTER normalization so it isn't cancelled out
            volume = app.VolumeKnob.Value;
            signal = signal * (volume / 80);  % divide by 80 since that's the max
        
            app.audioPlaying1 = audioplayer(signal, fs);
            play(app.audioPlaying1);
        end

        function signalOut = processAudioPipeline(app, slotNum)

            signalOut = app.audioData{slotNum};
            fs = app.sampleRate{slotNum};

            % Chopping (if user adjusted sliders)
            signalOut = applyChopping(app, signalOut, fs);
        
            % EQ
            signalOut = Equalizer(signalOut, fs, app.eqGains);
        
            % Speed
            speed = app.PlaybackSpeedSlider.Value;
            signalOut = PlaybackSpeed(signalOut, speed);
        
            % Echo
            % FIX: Echo now reads from the dry signalOut for the delayed tap,
            % not from echoSig. The old code fed back already-echoed samples
            % causing uncontrolled runaway feedback.
            delayTime = app.DelayTimeKnob.Value;
            feedback  = app.FeedbackKnob.Value;
            delaySamples = round(delayTime * fs);

            if delaySamples > 0
                echoSig = zeros(size(signalOut));
                for n = 1:length(signalOut)
                    echoSig(n) = signalOut(n);
                    if n > delaySamples
                        % FIX: use signalOut (dry) as the delay source,
                        % not echoSig (wet), to prevent compounding feedback.
                        echoSig(n) = echoSig(n) + feedback * signalOut(n - delaySamples);
                    end
                end
                signalOut = echoSig;
            end
   
            % Direction
            direction = app.PlaybackDirectionDropDown.Value;
            if strcmp(direction, "Reverse")
                signalOut = flipud(signalOut);
            end
        
        end

        function signalOut = applyChopping(app, signal, fs)

            % Get slider values
            startT = app.StartingTimeSlider.Value;
            endT   = app.EndingTimeSlider.Value;
        
            % Clamp values to avoid crashes
            startT = max(0, startT);
            endT   = min(endT, length(signal) / fs);
        
            % Convert to samples
            startIdx = max(1, round(startT * fs));
            endIdx   = min(length(signal), round(endT * fs));
        
            % Prevent bad ranges
            if endIdx <= startIdx
                signalOut = signal;
                return
            end
        
            % Chop signal
            signalOut = signal(startIdx:endIdx);
        end
        
        function saveProcessedAudio(app, pipelineType)

            if pipelineType == "features"
                dropdown    = app.ChooseSampleDropDown_1;
                pipelineFunc = @(s) processAudioPipeline(app, s);
            else
                dropdown    = app.ChooseSampleDropDown;
                pipelineFunc = @(s) processAudioPipeline(app, s);
            end
        
            slotName = dropdown.Value;
        
            if strcmp(slotName, 'Select')
                uialert(app.UIFigure, 'Please select sample', 'Error', 'Icon', 'error');
                return
            end
        
            originalSlot = str2double(slotName(end));
        
            if isempty(app.audioData{originalSlot})
                uialert(app.UIFigure, 'No sample loaded in this slot', 'Error', 'Icon', 'error');
                return
            end
        
            % Apply processing pipeline
            signal = pipelineFunc(originalSlot);
            fs     = app.sampleRate{originalSlot};

            choice = uiconfirm(app.UIFigure, "Where do you want to save this audio file?", ...
                "Save Options", "Options",["Save to Slot", "Save to Computer", "Cancel"]);

            if choice == "Save to Computer"
                [file, path] = uiputfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'});

                if file ~= 0
                    filename = fullfile(path,file);
                    audiowrite(filename, signal, fs);
                    uialert(app.UIFigure, "Saved to Computer", 'Sucess', 'Icon', 'success');
                end
                    return
            end
        
            % Find empty slots
            emptySlots = find(cellfun(@isempty, app.audioData));
        
            if ~isempty(emptySlots)
        
                choice = uiconfirm(app.UIFigure,...
                    "Save to empty slot or replace original slot?",...
                    "Save Options",...
                    "Options", ["Save to Empty Slot", "Replace Original", "Cancel"],...
                    "DefaultOption", 1);
        
                switch choice
                    case "Save to Empty Slot"
                        targetSlot = emptySlots(1);
                        app.audioData{targetSlot}  = signal;
                        app.sampleRate{targetSlot} = fs;
                        uialert(app.UIFigure, "Saved to Slot " + targetSlot, "Success", 'Icon', 'success');

                    case "Replace Original"
                        app.audioData{originalSlot}  = signal;
                        app.sampleRate{originalSlot} = fs;
                        uialert(app.UIFigure, "Replaced Slot " + originalSlot, "Success", 'Icon', 'success');

                    otherwise
                        return
                end
        
            else
                choice = uiconfirm(app.UIFigure,...
                    "All slots are full. Replace original slot?",...
                    "Save Options",...
                    "Options", ["Replace Original", "Cancel"],...
                    "DefaultOption", 1);
        
                if choice == "Replace Original"
                    app.audioData{originalSlot}  = signal;
                    app.sampleRate{originalSlot} = fs;
                    uialert(app.UIFigure, "Replaced Slot " + originalSlot, "Success", 'Icon', 'success');
                end
            end
        end

        % Helper: update chop sliders when a new sample is selected
        % FIX: Sliders now reflect the actual duration of the loaded audio.
        function updateChopSliders(app, slotNum)
            if isempty(app.audioData{slotNum})
                return
            end
            duration = length(app.audioData{slotNum}) / app.sampleRate{slotNum};
            app.StartingTimeSlider.Limits = [0, duration];
            app.StartingTimeSlider.Value  = 0;
            app.EndingTimeSlider.Limits   = [0, duration];
            app.EndingTimeSlider.Value    = duration;


        end

        % Clicked callback: ChooseWaveFunctionDropDown
        function ChooseWaveFunctionDropDownClicked(app, event)
           app.selectedWaveform = app.ChooseWaveFunctionDropDown.Value; %store the user selected waveform for later
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
         if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 261.63;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
            stop(app.audioPlaying1)
            end
            afreq = 293.7;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
            stop(app.audioPlaying1)
            end
            afreq = 329.6;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
             if(~isempty(app.audioPlaying1))
            stop(app.audioPlaying1)
            end
            afreq = 349.2;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
            end
            afreq = 392.0;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
            end
            afreq = 440;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
            stop(app.audioPlaying1)
            end
            afreq = 493.88;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
               [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
            
            elseif(strcmp(app.selectedWaveform, 'Sine'))
               [app.audioPlaying1, wave, time] = generatesine(afreq);
               play(app.audioPlaying1)
               plot(app.UIAxes_4, time, wave)
               xlim(app.UIAxes_4,[0,0.01])
               app.waveSave = wave;
                elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end

        end

        % Value changed function: ChooseSampleDropDown_3
        function ChooseSampleDropDown_3ValueChanged(app, event)
            app.selectedSlot = app.ChooseSampleDropDown_3.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Throw an error if the user tries to save without selecting the
           %slot

           if strcmp(app.selectedSlot, 'Select')
               uialert(app.UIFigure, 'Please select a slot!', 'You have no slot chosen ( ͡ಠ ʖ̯ ͡ಠ )', 'Icon', 'warning');
               return
           end
           
            %Throw an error if the user tries to save without generating
            %the tone
           
            if isempty(app.waveSave)
                uialert(app.UIFigure, 'Please generate a tone before saving!', 'You have nothing to save  ᕙ(⇀‸↼‶)ᕗ')
                return
            end

           choice = uiconfirm(app.UIFigure, "Where do you want to save this audio file?", ...
                "Save Options", "Options",["Save to Slot", "Save to Computer", "Cancel"]);
          
           if choice == "Save to Slot"
               slotNum = str2double(app.selectedSlot(end));
               emptySlots = find(cellfun(@isempty, app.audioData));
                if ~isempty(emptySlots)

                    choiceSlot = uiconfirm(app.UIFigure, "Save to empty slot or replace original slot?", ...
                        "Save Options", "Options", ["Save to Empty Slot", "Replace Original", "Cancel"], ...
                        "DefaultOption", 1);

                    switch choiceSlot
                        case "Save to Empty Slot"
                            targetSlot = emptySlots(1);
                            app.audioData{targetSlot} = app.waveSave;
                            app.sampleRate{targetSlot} = 44100;
                             uialert(app.UIFigure, "Saved to Slot " + targetSlot, "Success", 'Icon', 'success');

                        case "Replace Original"
                             app.audioData{slotNum} = app.waveSave;
                             app.sampleRate{slotNum} = 44100;
                             uialert(app.UIFigure, "Replaced Slot " + slotNum, "Success", 'Icon', 'success');
                        otherwise
                            return
                    end

                else
                    choiceSlot = uiconfirm(app.UIFigure,"All slots are full. Replace original slot?",...
                         "Save Options","Options", ["Replace Original", "Cancel"],...
                          "DefaultOption", 1);

                    if choiceSlot == "Replace Original"
                        app.audioData{slotNum} = app.waveSave;
                        app.sampleRate{slotNum} = 44100;
                        uialert(app.UIFigure, "Replaced Slot " + slotNum, "Success", 'Icon', 'success');
                    end
                end
           end

            if choice == "Save to Computer"
                [file, path] = uiputfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'});

                if file ~= 0
                    filename = fullfile(path,file);
                    audiowrite(filename, app.waveSave, 44100);
                    uialert(app.UIFigure, "Saved to Computer", 'Success', 'Icon', 'success');
                end 
                return
            end
          
        end

        % Button pushed function: Slot1Button
        function Slot1ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{1} = y;
            app.audioTable.sampleRate{1} = Fs;
            app.audioData{1} = y;
            app.sampleRate{1} = Fs;
            updateChopSliders(app, 1);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
            
        end

        % Button pushed function: Slot2Button
        function Slot2ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{2} = y;
            app.audioTable.sampleRate{2} = Fs;
            app.audioData{2} = y;
            app.sampleRate{2} = Fs;
            updateChopSliders(app, 2);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot3Button
        function Slot3ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{3} = y;
            app.audioTable.sampleRate{3} = Fs;
            app.audioData{3} = y;
            app.sampleRate{3} = Fs;
            updateChopSliders(app, 3);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot4Button
        function Slot4ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{4} = y;
            app.audioTable.sampleRate{4} = Fs;
            app.audioData{4} = y;
            app.sampleRate{4} = Fs;
            updateChopSliders(app, 4);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot5Button
        function Slot5ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{5} = y;
            app.audioTable.sampleRate{5} = Fs;
            app.audioData{5} = y;
            app.sampleRate{5} = Fs;
            updateChopSliders(app, 5);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot6Button
        function Slot6ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{6} = y;
            app.audioTable.sampleRate{6} = Fs;
            app.audioData{6} = y;
            app.sampleRate{6} = Fs;
            updateChopSliders(app, 6);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot7Button
        function Slot7ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{7} = y;
            app.audioTable.sampleRate{7} = Fs;
            app.audioData{7} = y;
            app.sampleRate{7} = Fs;
            updateChopSliders(app, 7);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot8Button
        function Slot8ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{8} = y;
            app.audioTable.sampleRate{8} = Fs;
            app.audioData{8} = y;
            app.sampleRate{8} = Fs;
            updateChopSliders(app, 8);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Button pushed function: Slot9Button
        function Slot9ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            [y,Fs] = audioread([filepath, filename]);
            if size(y,2) > 1
                y = mean(y,2); % convert stereo → mono
            end
            app.audioTable.audioData{9} = y;
            app.audioTable.sampleRate{9} = Fs;
            app.audioData{9} = y;
            app.sampleRate{9} = Fs;
            updateChopSliders(app, 9);
            uialert(app.UIFigure, 'Audio file successfully loaded!', 'Success', 'Icon', 'success');
        end

        % Value changed function: HzSlider_1, HzSlider_2, HzSlider_3, 
        % ...and 2 other components
        function EQChanged(app, event)
            app.eqGains = [app.HzSlider_1.Value, app.HzSlider_2.Value, app.HzSlider_3.Value, app.HzSlider_4.Value, app.HzSlider_5.Value]; 
        end

        % Value changed function: ChooseSampleDropDown
        function ChooseSampleDropDownValueChanged(app, event)
            slotName = app.ChooseSampleDropDown.Value;
            if strcmp(slotName, 'Select')
                return
            end
            slotNum = str2double(slotName(end));
            % Update chop sliders to match this sample's duration
            updateChopSliders(app, slotNum);
            app.ChooseSampleDropDown_1.Value = slotName;
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            saveProcessedAudio(app,"chopping");
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            saveProcessedAudio(app,"features");
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            slotName = app.ChooseSampleDropDown_1.Value;
            if strcmp(slotName, 'Select')
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return
            end
            slotNum = str2double(slotName(end));
            if isempty(app.audioTable.audioData{slotNum})
                uialert(app.UIFigure, 'The selected slot is empty. Please load an audio file first.', 'Empty Slot', 'Icon', 'warning');
                return
            end
            
            y = processAudioPipeline(app, slotNum);
            Fs = app.audioTable.sampleRate{slotNum};

            % Plot soundwave (processed)
            t = (0:length(y)-1) ./ Fs;
            plot(app.UIAxes_2, t, y);
            playProcessedAudio(app); 
        end

        % Value changed function: ChooseSampleDropDown_1
        function ChooseSampleDropDown_1ValueChanged(app, event)
            slotName = app.ChooseSampleDropDown_1.Value;
            if strcmp(slotName, 'Select')
                return
            end
            slotNum = str2double(slotName(end));
            updateChopSliders(app, slotNum);
            % Sync to Chopping dropdown
            app.ChooseSampleDropDown.Value = slotName;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(~isempty(app.audioPlaying1))
            stop(app.audioPlaying1);
            end
        end

        % Value changed function: ChooseSampleDropDown_2
        function ChooseSampleDropDown_2ValueChanged(app, event)
           slotName = app.ChooseSampleDropDown_2.Value;
            if strcmp(slotName, 'Select')
                return
            end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
             slotName = app.ChooseSampleDropDown_2.Value;
            if strcmp(slotName, 'Select')
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return
            end
            slotNum = str2double(slotName(end));
            if isempty(app.audioTable.audioData{slotNum})
                uialert(app.UIFigure, 'The selected slot is empty. Please load an audio file first.', 'Empty Slot', 'Icon', 'warning');
                return
            end
            
            y = app.audioTable.audioData{slotNum};
            Fs = app.audioTable.sampleRate{slotNum};

            % Plot soundwave (original)
            t = (0:length(y)-1) ./ Fs;
            plot(app.UIAxes_3, t, y);

            % Play audio sample
            app.audioPlaying2 = audioplayer(y,Fs);
            play(app.audioPlaying2);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            if (~isempty(app.audioPlaying2))
                stop(app.audioPlaying2);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
                   if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 277.18;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
               elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                   if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 311.13;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            
             elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                   if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 369.99;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
               elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
                   if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 415.30;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
               elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
                   if(~isempty(app.audioPlaying1))
                stop(app.audioPlaying1)
         end
            afreq = 466.16;
            if(strcmp(app.selectedWaveform, 'Sawtooth'))
                [app.audioPlaying1, wave, time] = generatesawtooth(afreq);
                play(app.audioPlaying1)
                plot(app.UIAxes_4, time, wave);
                xlim(app.UIAxes_4,[0,0.01]) %limits the x axis values to only a few periods
                app.waveSave = wave;
            
            elseif strcmp(app.selectedWaveform, 'Sine')
              [app.audioPlaying1, wave, time] = generatesine(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
               elseif strcmp(app.selectedWaveform, 'AM')
              [app.audioPlaying1, wave, time] = generateAM(afreq);
              play(app.audioPlaying1)
              plot(app.UIAxes_4, time, wave);
              xlim(app.UIAxes_4,[0,0.01])
              app.waveSave = wave;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SynthesizerPanel = uipanel(app.GridLayout);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes_4 = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes_4, 'Synthesizer')
            xlabel(app.UIAxes_4, {'Time'; ''})
            ylabel(app.UIAxes_4, 'Amplitude')
            zlabel(app.UIAxes_4, 'Z')
            app.EButton = uibutton(app.SynthesizerPanel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.BButton = uibutton(app.SynthesizerPanel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.Button_5 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.AButton = uibutton(app.SynthesizerPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.CButton = uibutton(app.SynthesizerPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = {'C'; ''};
            app.Button = uibutton(app.SynthesizerPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.DButton = uibutton(app.SynthesizerPanel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.Button_4 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.GButton = uibutton(app.SynthesizerPanel, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.Button_3 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.FButton = uibutton(app.SynthesizerPanel, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.Button_2 = uibutton(app.SynthesizerPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.ChooseSampleDropDown_4Label = uilabel(app.SynthesizerPanel);
            app.ChooseSampleDropDown_4Label.Text = 'Choose Sample';
            app.ChooseSampleDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.ChooseSampleDropDown_3.Items = {'Select', 'Slot 1', 'Slot 2', 'Slot 3', 'Slot 4', 'Slot 5', 'Slot 6', 'Slot 7', 'Slot 8', 'Slot 9'};
            app.ChooseSampleDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ChooseSampleDropDown_3ValueChanged, true);
            app.ChooseSampleDropDown_3.Value = 'Select';
            app.SaveButton = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save'; ''};
            app.FeaturesPanel = uipanel(app.GridLayout);
            app.FeaturesPanel.Title = 'Features';
            app.HzSlider_5Label = uilabel(app.FeaturesPanel);
            app.HzSlider_5Label.Text = '32 Hz';
            app.HzSlider_1 = uislider(app.FeaturesPanel);
            app.HzSlider_1.Limits = [-12 12];
            app.HzSlider_1.ValueChangedFcn = createCallbackFcn(app, @EQChanged, true);
            app.HzLabel = uilabel(app.FeaturesPanel);
            app.HzLabel.Text = '63 Hz';
            app.HzSlider_2 = uislider(app.FeaturesPanel);
            app.HzSlider_2.Limits = [-12 12];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @EQChanged, true);
            app.HzLabel_2 = uilabel(app.FeaturesPanel);
            app.HzLabel_2.Text = '126 Hz';
            app.HzSlider_3 = uislider(app.FeaturesPanel);
            app.HzSlider_3.Limits = [-12 12];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @EQChanged, true);
            app.HzLabel_3 = uilabel(app.FeaturesPanel);
            app.HzLabel_3.Text = '502 Hz';
            app.HzSlider_4 = uislider(app.FeaturesPanel);
            app.HzSlider_4.Limits = [-12 12];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @EQChanged, true);
            app.HzLabel_4 = uilabel(app.FeaturesPanel);
            app.HzLabel_4.Text = '1000 Hz';
            app.HzSlider_5 = uislider(app.FeaturesPanel);
            app.HzSlider_5.Limits = [-12 12];
            app.HzSlider_5.ValueChangedFcn = createCallbackFcn(app, @EQChanged, true);
            app.PlaybackDirectionDropDownLabel = uilabel(app.FeaturesPanel);
            app.PlaybackDirectionDropDownLabel.Text = 'Playback Direction';
            app.PlaybackDirectionDropDown = uidropdown(app.FeaturesPanel);
            app.PlaybackDirectionDropDown.Items = {'Forward', 'Reverse'};
            app.PlaybackDirectionDropDown.Value = 'Forward';
            app.SaveButton_3 = uibutton(app.FeaturesPanel, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = {'Save'; ''};
            app.ChooseSampleDropDown_2Label = uilabel(app.FeaturesPanel);
            app.ChooseSampleDropDown_2Label.Text = {'Choose '; 'Sample'};
            app.ChooseSampleDropDown_1 = uidropdown(app.FeaturesPanel);
            app.ChooseSampleDropDown_1.Items = {'Select', 'Slot 1', 'Slot 2', 'Slot 3', 'Slot 4', 'Slot 5', 'Slot 6', 'Slot 7', 'Slot 8', 'Slot 9'};
            app.ChooseSampleDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ChooseSampleDropDown_1ValueChanged, true);
            app.ChooseSampleDropDown_1.Value = 'Select';
            app.PlaybackSpeedLabel = uilabel(app.FeaturesPanel);
            app.PlaybackSpeedLabel.Text = {'Playback'; 'Speed'};
            app.PlaybackSpeedSlider = uislider(app.FeaturesPanel);
            app.PlaybackSpeedSlider.Limits = [0.25 2];
            app.PlaybackSpeedSlider.Value = 1;
            app.FeedbackKnob = uiknob(app.FeaturesPanel, 'continuous');
            app.FeedbackKnob.Limits = [0 0.8];
            app.PlayButton = uibutton(app.FeaturesPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.EchoSliderLabel_2 = uilabel(app.FeaturesPanel);
            app.EchoSliderLabel_2.Text = 'Echo';
            app.FeedbackKnobLabel_2 = uilabel(app.FeaturesPanel);
            app.FeedbackKnobLabel_2.Text = 'Feedback';
            app.DelayTimeKnob_3Label = uilabel(app.FeaturesPanel);
            app.DelayTimeKnob_3Label.Text = 'Delay Time';
            app.DelayTimeKnob = uiknob(app.FeaturesPanel, 'continuous');
            app.DelayTimeKnob.Limits = [0 0.9];
            app.VolumeKnob = uiknob(app.FeaturesPanel, 'continuous');
            app.VolumeKnob.Limits = [0 80];
            app.VolumeKnob.Value = 10;
            app.PlaybackDirectionDropDownLabel_3 = uilabel(app.FeaturesPanel);
            app.PlaybackDirectionDropDownLabel_3.Text = 'Volume';
            app.DelayTimeKnobLabel_2 = uilabel(app.FeaturesPanel);
            app.DelayTimeKnobLabel_2.Text = 'Equalizer';
            app.StopButton = uibutton(app.FeaturesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ChoppingPanel = uipanel(app.GridLayout);
            app.ChoppingPanel.Title = 'Chopping';
            app.SaveButton_2 = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = {'Save'; ''};
            app.ChooseSampleLabel = uilabel(app.ChoppingPanel);
            app.ChooseSampleLabel.Text = {'Choose '; 'Sample'};
            app.ChooseSampleDropDown = uidropdown(app.ChoppingPanel);
            app.ChooseSampleDropDown.Items = {'Select', 'Slot 1', 'Slot 2', 'Slot 3', 'Slot 4', 'Slot 5', 'Slot 6', 'Slot 7', 'Slot 8', 'Slot 9'};
            app.ChooseSampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSampleDropDownValueChanged, true);
            app.ChooseSampleDropDown.Value = 'Select';
            app.SelectSamplePanel = uipanel(app.GridLayout);
            app.SelectSamplePanel.Title = 'Select Sample';
            app.UIAxes_3 = uiaxes(app.SelectSamplePanel);
            title(app.UIAxes_3, 'Soundwave (Original)')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.SelectSamplePanel);
            title(app.UIAxes_2, 'Soundwave (Processed)')
            xlabel(app.UIAxes_2, {'Time'; ''})
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.Slot1Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot1Button.ButtonPushedFcn = createCallbackFcn(app, @Slot1ButtonPushed, true);
            app.Slot1Button.Text = 'Slot 1';
            app.Slot2Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot2Button.ButtonPushedFcn = createCallbackFcn(app, @Slot2ButtonPushed, true);
            app.Slot2Button.Text = 'Slot 2';
            app.Slot3Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot3Button.ButtonPushedFcn = createCallbackFcn(app, @Slot3ButtonPushed, true);
            app.Slot3Button.Text = 'Slot 3';
            app.Slot4Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot4Button.ButtonPushedFcn = createCallbackFcn(app, @Slot4ButtonPushed, true);
            app.Slot4Button.Text = 'Slot 4';
            app.Slot5Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot5Button.ButtonPushedFcn = createCallbackFcn(app, @Slot5ButtonPushed, true);
            app.Slot5Button.Text = 'Slot 5';
            app.Slot6Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot6Button.ButtonPushedFcn = createCallbackFcn(app, @Slot6ButtonPushed, true);
            app.Slot6Button.Text = 'Slot 6';
            app.Slot7Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot7Button.ButtonPushedFcn = createCallbackFcn(app, @Slot7ButtonPushed, true);
            app.Slot7Button.Text = 'Slot 7';
            app.Slot8Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot8Button.ButtonPushedFcn = createCallbackFcn(app, @Slot8ButtonPushed, true);
            app.Slot8Button.Text = 'Slot 8';
            app.Slot9Button = uibutton(app.SelectSamplePanel, 'push');
            app.Slot9Button.ButtonPushedFcn = createCallbackFcn(app, @Slot9ButtonPushed, true);
            app.Slot9Button.Text = 'Slot 9';
            app.ChooseSampleDropDown_2Label_2 = uilabel(app.SelectSamplePanel);
            app.ChooseSampleDropDown_2Label_2.Text = 'Choose Sample';
            app.ChooseSampleDropDown_2 = uidropdown(app.SelectSamplePanel);
            app.ChooseSampleDropDown_2.Items = {'Select', 'Slot 1', 'Slot 2', 'Slot 3', 'Slot 4', 'Slot 5', 'Slot 6', 'Slot 7', 'Slot 8', 'Slot 9'};
            app.ChooseSampleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChooseSampleDropDown_2ValueChanged, true);
            app.ChooseSampleDropDown_2.Value = 'Select';
            app.PlayButton_2 = uibutton(app.SelectSamplePanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play ';
            app.StopButton_2 = uibutton(app.SelectSamplePanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ChooseWaveFunctionDropDownLabel = uilabel(app.GridLayout);
            app.ChooseWaveFunctionDropDownLabel.Text = 'Choose Wave Function';
            app.ChooseWaveFunctionDropDown = uidropdown(app.GridLayout);
            app.ChooseWaveFunctionDropDown.Items = {'Sine', 'Sawtooth', 'AM'};
            app.ChooseWaveFunctionDropDown.ClickedFcn = createCallbackFcn(app, @ChooseWaveFunctionDropDownClicked, true);
            app.ChooseWaveFunctionDropDown.Value = 'Sine';
            app.StartingTimeSliderLabel = uilabel(app.GridLayout);
            app.StartingTimeSliderLabel.Text = 'Starting Time';
            app.StartingTimeSlider = uislider(app.GridLayout);
            app.EndingTimeSliderLabel = uilabel(app.GridLayout);
            app.EndingTimeSliderLabel.Text = 'Ending Time';
            app.EndingTimeSlider = uislider(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiosamplergui_newfseventhedition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initializeApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end