classdef finalproject1_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        VolumeSlider              matlab.ui.control.Slider
        VolumeSliderLabel         matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        PlaybackDirectionSwitch   matlab.ui.control.Switch
        PlaybackDirectionLabel    matlab.ui.control.Label
        SelectTrackDropDown       matlab.ui.control.DropDown
        SelectTrackDropDownLabel  matlab.ui.control.Label
        Track9Button              matlab.ui.control.Button
        Track8Button              matlab.ui.control.Button
        Track7Button              matlab.ui.control.Button
        Track6Button              matlab.ui.control.Button
        Track5Button              matlab.ui.control.Button
        Track4Button              matlab.ui.control.Button
        Track3Button              matlab.ui.control.Button
        Track2Button              matlab.ui.control.Button
        Track1Button              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioData % Cell array storing audio data
        sampleRate % Cell array storing sampling rate 
        selectedTrack = 1; % Currently selected track (default=1)
        playFile % Audioplayer object
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set storage for audio data and sample rates
            app.audioData = cell(1,9);
            app.sampleRate = cell(1,9);
        end

        % Button pushed function: Track1Button
        function Track1ButtonPushed(app, event)
            % Open file browser and load selected file into Track 1
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

             % Throw an error if user cancels
            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{1} = y;
            app.sampleRate{1} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');
        
        end

        % Button pushed function: Track2Button
        function Track2ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{2} = y;
            app.sampleRate{2} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track3Button
        function Track3ButtonPushed(app, event)
             [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{3} = y;
            app.sampleRate{3} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track4Button
        function Track4ButtonPushed(app, event)
             [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{4} = y;
            app.sampleRate{4} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track5Button
        function Track5ButtonPushed(app, event)
             [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{5} = y;
            app.sampleRate{5} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track6Button
        function Track6ButtonPushed(app, event)
              [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{6} = y;
            app.sampleRate{6} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track7Button
        function Track7ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{7} = y;
            app.sampleRate{7} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track8Button
        function Track8ButtonPushed(app, event)
              [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{8} = y;
            app.sampleRate{8} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Button pushed function: Track9Button
        function Track9ButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.wav; *.mp3; *.m4a; *.flac; *.ogg; *.aiff; *.wma'},...
                'Select Audio File to Open');

            if isequal(filename, 0)
                uialert(app.UIFigure, 'No audio file selected', 'Error', 'Icon', 'error');
                return
            end

            fullname = [filepath, filename];
            [y,Fs] = audioread(fullname);
            app.audioData{9} = y;
            app.sampleRate{9} = Fs;
            uialert(app.UIFigure, 'Audio file successfully loaded', 'Success', 'Icon', 'success');

        end

        % Value changed function: SelectTrackDropDown
        function SelectTrackDropDownValueChanged(app, event)
            % Convert the selected track to a number
            valueString = app.SelectTrackDropDown.Value;
            valueChar = valueString(7);
            app.selectedTrack = str2double(valueChar);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            % Get selected track and sample rate
            y = app.audioData{app.selectedTrack};
            Fs = app.sampleRate{app.selectedTrack};

            % Reverse the track if the switch is set to Backward
            if strcmp(app.PlaybackDirectionSwitch.Value, 'Backward')
                y = flipud(y);
            end 
            
            % Volume control (scale 0-100 converted to 0-1)
            vol = app.VolumeSlider.Value ./ 100;
            y = y .* vol;

            % Plot soundwave
            t = (0:length(y)-1) ./ Fs;
            plot(app.UIAxes, t, y);

            % Play audio
            app.playFile = audioplayer(y,Fs);
            play(app.playFile);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % Pause the playing track
            pause(app.playFile);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Soundwave')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Track1Button = uibutton(app.UIFigure, 'push');
            app.Track1Button.ButtonPushedFcn = createCallbackFcn(app, @Track1ButtonPushed, true);
            app.Track1Button.Text = 'Track 1';
            app.Track2Button = uibutton(app.UIFigure, 'push');
            app.Track2Button.ButtonPushedFcn = createCallbackFcn(app, @Track2ButtonPushed, true);
            app.Track2Button.Text = 'Track 2';
            app.Track3Button = uibutton(app.UIFigure, 'push');
            app.Track3Button.ButtonPushedFcn = createCallbackFcn(app, @Track3ButtonPushed, true);
            app.Track3Button.Text = 'Track 3';
            app.Track4Button = uibutton(app.UIFigure, 'push');
            app.Track4Button.ButtonPushedFcn = createCallbackFcn(app, @Track4ButtonPushed, true);
            app.Track4Button.Text = 'Track 4';
            app.Track5Button = uibutton(app.UIFigure, 'push');
            app.Track5Button.ButtonPushedFcn = createCallbackFcn(app, @Track5ButtonPushed, true);
            app.Track5Button.Text = 'Track 5';
            app.Track6Button = uibutton(app.UIFigure, 'push');
            app.Track6Button.ButtonPushedFcn = createCallbackFcn(app, @Track6ButtonPushed, true);
            app.Track6Button.Text = 'Track 6';
            app.Track7Button = uibutton(app.UIFigure, 'push');
            app.Track7Button.ButtonPushedFcn = createCallbackFcn(app, @Track7ButtonPushed, true);
            app.Track7Button.Text = 'Track 7';
            app.Track8Button = uibutton(app.UIFigure, 'push');
            app.Track8Button.ButtonPushedFcn = createCallbackFcn(app, @Track8ButtonPushed, true);
            app.Track8Button.Text = 'Track 8';
            app.Track9Button = uibutton(app.UIFigure, 'push');
            app.Track9Button.ButtonPushedFcn = createCallbackFcn(app, @Track9ButtonPushed, true);
            app.Track9Button.Text = 'Track 9';
            app.SelectTrackDropDownLabel = uilabel(app.UIFigure);
            app.SelectTrackDropDownLabel.Text = 'Select Track';
            app.SelectTrackDropDown = uidropdown(app.UIFigure);
            app.SelectTrackDropDown.Items = {'Track1', 'Track 2', 'Track 3', 'Track 4', 'Track 5', 'Track 6', 'Track 7', 'Track 8', 'Track 9'};
            app.SelectTrackDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTrackDropDownValueChanged, true);
            app.SelectTrackDropDown.Value = 'Track1';
            app.PlaybackDirectionLabel = uilabel(app.UIFigure);
            app.PlaybackDirectionLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Backward', 'Forward'};
            app.PlaybackDirectionSwitch.Value = 'Backward';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play Button';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop Button';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalproject1_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end